classdef transformerDesignEvaluation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BmaxTEditField                  matlab.ui.control.NumericEditField
        BmaxTEditFieldLabel             matlab.ui.control.Label
        BmaxLabel                       matlab.ui.control.Label
        IrmsmaxsecondaryEditField       matlab.ui.control.NumericEditField
        IrmsmaxsecondaryEditFieldLabel  matlab.ui.control.Label
        IrmsMaxprimaryEditField         matlab.ui.control.NumericEditField
        IrmsMaxprimaryEditFieldLabel    matlab.ui.control.Label
        TransformerRMScurrentLabel      matlab.ui.control.Label
        ResultsLabel                    matlab.ui.control.Label
        IIIIIIIIIIIIIIIIIIIIIIIIIIIIIIIIIIILabel  matlab.ui.control.Label
        TransformerVoltagesLabel        matlab.ui.control.Label
        VmaxsecondaryEditField          matlab.ui.control.NumericEditField
        VmaxsecondaryEditFieldLabel     matlab.ui.control.Label
        VmaxprimaryEditField            matlab.ui.control.NumericEditField
        VmaxprimaryEditFieldLabel       matlab.ui.control.Label
        MagnetizinginductanceEditField  matlab.ui.control.NumericEditField
        MagnetizinginductanceEditFieldLabel  matlab.ui.control.Label
        LeakageinductanceEditField      matlab.ui.control.NumericEditField
        LeakageinductanceEditFieldLabel  matlab.ui.control.Label
        WindingParametersLabel          matlab.ui.control.Label
        Cudiametersecondarym2EditField  matlab.ui.control.NumericEditField
        Cudiametersecondarym2EditFieldLabel  matlab.ui.control.Label
        Cudiameterprimarym2EditField    matlab.ui.control.NumericEditField
        Cudiameterprimarym2EditFieldLabel  matlab.ui.control.Label
        MeanwindinglengthmEditField     matlab.ui.control.NumericEditField
        MeanwindinglengthmEditFieldLabel  matlab.ui.control.Label
        kEditField                      matlab.ui.control.NumericEditField
        kEditFieldLabel                 matlab.ui.control.Label
        betaEditField                   matlab.ui.control.NumericEditField
        betaEditFieldLabel              matlab.ui.control.Label
        alphaEditField                  matlab.ui.control.NumericEditField
        alphaEditFieldLabel             matlab.ui.control.Label
        CoreParametersLabel             matlab.ui.control.Label
        TransformerParametersLabel      matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        Corecrosssectionaream2EditField  matlab.ui.control.NumericEditField
        Corecrosssectionaream2EditFieldLabel  matlab.ui.control.Label
        NoofprimaryturnsEditField       matlab.ui.control.NumericEditField
        NoofprimaryturnsEditFieldLabel  matlab.ui.control.Label
        TurnsratioEditField             matlab.ui.control.NumericEditField
        TurnsratioEditFieldLabel        matlab.ui.control.Label
        UIAxesSec                       matlab.ui.control.UIAxes
        UIAxesPri                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TurnsratioEditField
        function TurnsratioEditFieldValueChanged(app, event)
            in.N = app.TurnsratioEditField.Value;
            
        end

        % Value changed function: NoofprimaryturnsEditField
        function NoofprimaryturnsEditFieldValueChanged(app, event)
            in.N1 = app.NoofprimaryturnsEditField.Value;
            
        end

        % Value changed function: Corecrosssectionaream2EditField
        function Corecrosssectionaream2EditFieldValueChanged(app, event)
            in.Ac = app.Corecrosssectionaream2EditField.Value;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            in.Ac = app.Corecrosssectionaream2EditField.Value;
            in.N = app.TurnsratioEditField.Value;
            in.N1 = app.NoofprimaryturnsEditField.Value;
            in.Lk = app.LeakageinductanceEditField.Value;      
            in.Lm = app.MagnetizinginductanceEditField.Value;
            in.a = app.alphaEditField.Value;
            in.b = app.betaEditField.Value;
            in.k = app.kEditField.Value;
            in.MWL = app.MeanwindinglengthmEditField.Value;
            in.Acu_pri = app.Cudiameterprimarym2EditField.Value;
            in.Acu_sec = app.Cudiametersecondarym2EditField.Value;

            out = four_phase_transformerDesign(in);

            app.VmaxprimaryEditField.Value = out.Vmax_pri;
            app.VmaxsecondaryEditField.Value = out.Vmax_sec;
            app.IrmsMaxprimaryEditField.Value = out.IrmsMax_tr_pri;
            app.IrmsmaxsecondaryEditField.Value = out.IrmsMax_tr_sec;
            app.BmaxTEditField.Value = out.Bmax;

            stairs(app.UIAxesPri,out.tpri_Bmax*1e6,out.Vpri_Bmax)
            grid(app.UIAxesPri,'on')

            stairs(app.UIAxesSec,out.tsec_Bmax*1e6,out.Vsec_Bmax)
            grid(app.UIAxesSec,'on')
        end

        % Value changed function: LeakageinductanceEditField
        function LeakageinductanceEditFieldValueChanged(app, event)
            in.Lk = app.LeakageinductanceEditField.Value;
            
        end

        % Value changed function: MagnetizinginductanceEditField
        function MagnetizinginductanceEditFieldValueChanged(app, event)
            in.Lm = app.MagnetizinginductanceEditField.Value;
            
        end

        % Value changed function: alphaEditField
        function alphaEditFieldValueChanged(app, event)
            in.a = app.alphaEditField.Value;
            
        end

        % Value changed function: betaEditField
        function betaEditFieldValueChanged(app, event)
            in.b = app.betaEditField.Value;
            
        end

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            in.k = app.kEditField.Value;
            
        end

        % Value changed function: MeanwindinglengthmEditField
        function MeanwindinglengthmEditFieldValueChanged(app, event)
            in.MWL = app.MeanwindinglengthmEditField.Value;
            
        end

        % Value changed function: Cudiameterprimarym2EditField
        function Cudiameterprimarym2EditFieldValueChanged(app, event)
            in.Acu_pri = app.Cudiameterprimarym2EditField.Value;
            
        end

        % Value changed function: Cudiametersecondarym2EditField
        function Cudiametersecondarym2EditFieldValueChanged(app, event)
            in.Acu_sec = app.Cudiametersecondarym2EditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesPri = uiaxes(app.UIFigure);
            title(app.UIAxesPri, 'Primary Voltage', 'Interpreter', 'latex')
            xlabel(app.UIAxesPri, 'time [us]', 'Interpreter', 'latex')
            ylabel(app.UIAxesPri, 'Voltage', 'Interpreter', 'latex')
            zlabel(app.UIAxesPri, 'Z', 'Interpreter', 'latex')
            app.UIAxesSec = uiaxes(app.UIFigure);
            title(app.UIAxesSec, 'Secondary Voltage', 'Interpreter', 'latex')
            xlabel(app.UIAxesSec, 'time [us]', 'Interpreter', 'latex')
            ylabel(app.UIAxesSec, 'Voltage', 'Interpreter', 'latex')
            zlabel(app.UIAxesSec, 'Z', 'Interpreter', 'latex')
            app.TurnsratioEditFieldLabel = uilabel(app.UIFigure);
            app.TurnsratioEditFieldLabel.Text = 'Turns ratio';
            app.TurnsratioEditField = uieditfield(app.UIFigure, 'numeric');
            app.TurnsratioEditField.ValueChangedFcn = createCallbackFcn(app, @TurnsratioEditFieldValueChanged, true);
            app.TurnsratioEditField.Value = 0.66667;
            app.NoofprimaryturnsEditFieldLabel = uilabel(app.UIFigure);
            app.NoofprimaryturnsEditFieldLabel.Text = 'No. of primary turns';
            app.NoofprimaryturnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofprimaryturnsEditField.ValueChangedFcn = createCallbackFcn(app, @NoofprimaryturnsEditFieldValueChanged, true);
            app.NoofprimaryturnsEditField.Value = 18;
            app.Corecrosssectionaream2EditFieldLabel = uilabel(app.UIFigure);
            app.Corecrosssectionaream2EditFieldLabel.Text = 'Core cross section area [m2]';
            app.Corecrosssectionaream2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Corecrosssectionaream2EditField.ValueDisplayFormat = '%.2g';
            app.Corecrosssectionaream2EditField.ValueChangedFcn = createCallbackFcn(app, @Corecrosssectionaream2EditFieldValueChanged, true);
            app.Corecrosssectionaream2EditField.Value = 0.006;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TransformerParametersLabel = uilabel(app.UIFigure);
            app.TransformerParametersLabel.Text = 'Transformer Parameters';
            app.CoreParametersLabel = uilabel(app.UIFigure);
            app.CoreParametersLabel.Text = 'Core Parameters';
            app.alphaEditFieldLabel = uilabel(app.UIFigure);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.alphaEditField.ValueDisplayFormat = '%.2g';
            app.alphaEditField.ValueChangedFcn = createCallbackFcn(app, @alphaEditFieldValueChanged, true);
            app.alphaEditField.Value = 1.722;
            app.betaEditFieldLabel = uilabel(app.UIFigure);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.UIFigure, 'numeric');
            app.betaEditField.ValueDisplayFormat = '%.2g';
            app.betaEditField.ValueChangedFcn = createCallbackFcn(app, @betaEditFieldValueChanged, true);
            app.betaEditField.Value = 2.0975;
            app.kEditFieldLabel = uilabel(app.UIFigure);
            app.kEditFieldLabel.Text = 'k ';
            app.kEditField = uieditfield(app.UIFigure, 'numeric');
            app.kEditField.ValueDisplayFormat = '%.2g';
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.kEditField.Value = 8.138e-06;
            app.MeanwindinglengthmEditFieldLabel = uilabel(app.UIFigure);
            app.MeanwindinglengthmEditFieldLabel.Text = 'Mean winding length [m]';
            app.MeanwindinglengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeanwindinglengthmEditField.ValueDisplayFormat = '%.2g';
            app.MeanwindinglengthmEditField.ValueChangedFcn = createCallbackFcn(app, @MeanwindinglengthmEditFieldValueChanged, true);
            app.MeanwindinglengthmEditField.Value = 1;
            app.Cudiameterprimarym2EditFieldLabel = uilabel(app.UIFigure);
            app.Cudiameterprimarym2EditFieldLabel.Text = 'Cu diameter primary [m2]';
            app.Cudiameterprimarym2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Cudiameterprimarym2EditField.ValueDisplayFormat = '%.2g';
            app.Cudiameterprimarym2EditField.ValueChangedFcn = createCallbackFcn(app, @Cudiameterprimarym2EditFieldValueChanged, true);
            app.Cudiameterprimarym2EditField.Value = 5.5e-05;
            app.Cudiametersecondarym2EditFieldLabel = uilabel(app.UIFigure);
            app.Cudiametersecondarym2EditFieldLabel.Text = 'Cu diameter secondary [m2]';
            app.Cudiametersecondarym2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Cudiametersecondarym2EditField.ValueDisplayFormat = '%.2g';
            app.Cudiametersecondarym2EditField.ValueChangedFcn = createCallbackFcn(app, @Cudiametersecondarym2EditFieldValueChanged, true);
            app.Cudiametersecondarym2EditField.Value = 4.25e-05;
            app.WindingParametersLabel = uilabel(app.UIFigure);
            app.WindingParametersLabel.Text = 'Winding Parameters';
            app.LeakageinductanceEditFieldLabel = uilabel(app.UIFigure);
            app.LeakageinductanceEditFieldLabel.Text = 'Leakage inductance';
            app.LeakageinductanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeakageinductanceEditField.ValueChangedFcn = createCallbackFcn(app, @LeakageinductanceEditFieldValueChanged, true);
            app.LeakageinductanceEditField.Value = 2.4216e-06;
            app.MagnetizinginductanceEditFieldLabel = uilabel(app.UIFigure);
            app.MagnetizinginductanceEditFieldLabel.Text = 'Magnetizing inductance';
            app.MagnetizinginductanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.MagnetizinginductanceEditField.ValueDisplayFormat = '%.2g';
            app.MagnetizinginductanceEditField.ValueChangedFcn = createCallbackFcn(app, @MagnetizinginductanceEditFieldValueChanged, true);
            app.MagnetizinginductanceEditField.Value = 0.00024216;
            app.VmaxprimaryEditFieldLabel = uilabel(app.UIFigure);
            app.VmaxprimaryEditFieldLabel.Text = 'Vmax primary';
            app.VmaxprimaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.VmaxsecondaryEditFieldLabel = uilabel(app.UIFigure);
            app.VmaxsecondaryEditFieldLabel.Text = 'Vmax secondary';
            app.VmaxsecondaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransformerVoltagesLabel = uilabel(app.UIFigure);
            app.TransformerVoltagesLabel.Text = 'Transformer Voltages';
            app.IIIIIIIIIIIIIIIIIIIIIIIIIIIIIIIIIIILabel = uilabel(app.UIFigure);
            app.IIIIIIIIIIIIIIIIIIIIIIIIIIIIIIIIIIILabel.Text = {'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; 'I'; ''};
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.TransformerRMScurrentLabel = uilabel(app.UIFigure);
            app.TransformerRMScurrentLabel.Text = 'Transformer RMS current';
            app.IrmsMaxprimaryEditFieldLabel = uilabel(app.UIFigure);
            app.IrmsMaxprimaryEditFieldLabel.Text = 'Irms Max primary';
            app.IrmsMaxprimaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.IrmsmaxsecondaryEditFieldLabel = uilabel(app.UIFigure);
            app.IrmsmaxsecondaryEditFieldLabel.Text = 'Irms max secondary';
            app.IrmsmaxsecondaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.BmaxLabel = uilabel(app.UIFigure);
            app.BmaxLabel.Text = 'Bmax';
            app.BmaxTEditFieldLabel = uilabel(app.UIFigure);
            app.BmaxTEditFieldLabel.Text = 'Bmax [T]';
            app.BmaxTEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transformerDesignEvaluation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end